classdef webcam_simple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        ScreenshotLabel      matlab.ui.control.Label
        WebcamLabel          matlab.ui.control.Label
        saveScreen_button    matlab.ui.control.Button
        getScreen_button     matlab.ui.control.Button
        toggleWebcam_button  matlab.ui.control.StateButton
        screen1_axes         matlab.ui.control.UIAxes
        webcam1_axes         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updatepreview(app, obj, event, himage)
            
            % Display image data.
            img = flip(event.Data, 2);            
            set(himage, 'CData', img, 'ButtonDownFcn', @app.mouseClickedImg);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function webcam_simple_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to webcam_simple (see VARARGIN)
            
            %% Recreate axes manually as axes as uiaxes may create lag (https://de.mathworks.com/matlabcentral/answers/582209-video-preview-has-growing-delay-when-used-in-uifigure-but-not-in-normal-figure#answer_484589)
            ax1 = axes(app.figure1, 'Visible', 'off', 'Units', 'pixels', 'Position', app.screen1_axes.Position);
            ax2 = axes(app.figure1, 'Visible', 'off', 'Units', 'pixels', 'Position', app.webcam1_axes.Position);
            delete(handles.screen1_axes)
            delete(handles.webcam1_axes)
            handles.screen1_axes = ax1; % matlab.graphics.axis.Axes instead of matlab.ui.control.UIAxes
            handles.webcam1_axes = ax2; % matlab.graphics.axis.Axes instead of matlab.ui.control.UIAxes

            %% setup video input: try to find correct adapter and suitable resolutions & color format
            imaqreset;
            if isunix
                driver = 'linuxvideo';
            elseif ismac
                driver = 'macvideo';
            elseif ispc % windows
                driver = 'winvideo';
            else
                error('Unkown OS')
            end
            vid = videoinput(driver, 1);
            format = vid.VideoFormat;
            formats = imaqhwinfo(driver).DeviceInfo.SupportedFormats';
            if any(contains(formats, regexprep(format, '[0-9]+x[0-9]+', '320x240')))
                format = regexprep(format, '[0-9]+x[0-9]+', '320x240');
            elseif any(contains(formats, regexprep(format, '[0-9]+x[0-9]+', '640x480')))
                format = regexprep(format, '[0-9]+x[0-9]+', '640x480');
            elseif any(contains(formats, regexprep(format, '[0-9]+x[0-9]+', '800x600')))
                format = regexprep(format, '[0-9]+x[0-9]+', '800x600');
            else
                error('Need to select a suitable resolution manually')
            end
%             if any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'RGB24_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'RGB24_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'BGR24_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'BGR24_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'I420_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'I420_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'YUY2_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'YUY2_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'YV12_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'YV12_');
%             else
%                 error('Unkown color format')
%             end
            handles.vidInput1 = videoinput(driver, 1, format);
            handles.vidInput1.ReturnedColorSpace = 'rgb'; % fixes ycbcr2rgb being required manually
            handles.pbuttonToggleCam.Enable = 'on';
            guidata(hObject, handles);

            %% create image buffer for visualization of filters & register custom preview callback
            vidRes = get(handles.vidInput1, 'VideoResolution');
            nBands = get(handles.vidInput1, 'NumberOfBands');
            img_empty = zeros(vidRes(2), vidRes(1), nBands);
            handles.hImageInput1 = imshow(img_empty, 'parent', handles.webcam1_axes);
            setappdata(handles.hImageInput1, 'UpdatePreviewWindowFcn', @app.updatepreview);
            
            % Better use UpdatePreviewWindowFcn as FramesAcquiredFcn events are guaranteed to execute and can be queued, which may cause delay
            % > https://de.mathworks.com/matlabcentral/answers/95642-how-are-events-queued-when-using-the-image-acquisition-toolbox-callback-functions
            % From documentation of 'preview':
            %    The 'UpdatePreviewWindowFcn' will not necessarily be called for every frame that is acquired.
            %    If a new frame is acquired and the 'UpdatePreviewWindowFcn' for the previous frame has not yet finished
            %    executing, no update will be generated for the new frame.  If you need to execute a function for every acquired frame,
            %    use the FramesAcquiredFcn instead.
            %set(handles.vidInput, 'FramesPerTrigger', Inf);
            %set(handles.vidInput, 'FramesAcquiredFcnCount', 1);
            %set(handles.vidInput, 'FramesAcquiredFcn', @app.updatefilter);
            
            %% Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: getScreen_button
        function getScreen_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getScreen_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.frame1 = getsnapshot(handles.vidInput1);
            
            % Update handles structure
            guidata(hObject, handles);
            imshow(handles.frame1, 'parent', handles.screen1_axes);
        end

        % Button pushed function: saveScreen_button
        function saveScreen_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveScreen_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            img = handles.frame1;
            imwrite(img, 'screenshot.png');
            disp('Screeshot saved to file ''screenshot.png''');
            
            guidata(hObject, handles);
        end

        % Value changed function: toggleWebcam_button
        function toggleWebcam_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toggleWebcam_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (strcmp(get(handles.toggleWebcam_button, 'String'), 'Start Webcam'))
                preview(handles.vidInput1, handles.hImageInput1);
                set(handles.toggleWebcam_button, 'String', 'Stop Webcam');
            else
                stoppreview(handles.vidInput1);
                set(handles.toggleWebcam_button, 'String', 'Start Webcam');
            end
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'webcam_simple';
            app.figure1.Tag = 'figure1';
            app.webcam1_axes = uiaxes(app.figure1);
            app.webcam1_axes.Visible = 'off';
            app.webcam1_axes.Tag = 'webcam1_axes';
            app.screen1_axes = uiaxes(app.figure1);
            app.screen1_axes.Visible = 'off';
            app.screen1_axes.Tag = 'screen1_axes';
            app.toggleWebcam_button = uibutton(app.figure1, 'state');
            app.toggleWebcam_button.ValueChangedFcn = createCallbackFcn(app, @toggleWebcam_button_Callback, true);
            app.toggleWebcam_button.Tag = 'toggleWebcam_button';
            app.toggleWebcam_button.Text = 'Start Webcam';
            app.getScreen_button = uibutton(app.figure1, 'push');
            app.getScreen_button.ButtonPushedFcn = createCallbackFcn(app, @getScreen_button_Callback, true);
            app.getScreen_button.Tag = 'getScreen_button';
            app.getScreen_button.Text = 'Screenshot';
            app.saveScreen_button = uibutton(app.figure1, 'push');
            app.saveScreen_button.ButtonPushedFcn = createCallbackFcn(app, @saveScreen_button_Callback, true);
            app.saveScreen_button.Tag = 'saveScreen_button';
            app.saveScreen_button.Text = 'Save Screenshot';
            app.WebcamLabel = uilabel(app.figure1);
            app.WebcamLabel.Text = 'Webcam';
            app.ScreenshotLabel = uilabel(app.figure1);
            app.ScreenshotLabel.Text = 'Screenshot';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webcam_simple(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)webcam_simple_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end