classdef trackingWebcam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        OpenMenuItem      matlab.ui.container.Menu
        ExportMenuItem    matlab.ui.container.Menu
        CloseMenuItem     matlab.ui.container.Menu
        pbuttonToggleCam  matlab.ui.control.Button
        popupmenu1        matlab.ui.control.DropDown
        axes1             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function blockMatcher(app, M)

            global hAxes;            
            global trackingPoint;
            
            if ~isempty(trackingPoint)
                p = TDLS(M, trackingPoint(1), trackingPoint(2), 25);
                trackingPoint(1) = p(1, 2);
                trackingPoint(2) = p(2, 2);
            
                h = findobj(hAxes, 'Type', 'line');
                delete(h);
                hold(hAxes, 'on');
                plot(hAxes, trackingPoint(1), trackingPoint(2), 'y+', 'MarkerSize', 15, 'LineWidth', 5);
                plot(hAxes, trackingPoint(1), trackingPoint(2), 'b+', 'MarkerSize', 10, 'LineWidth', 3);
                hold(hAxes, 'off');
            end
        end
        
        function particleFilter(app, M)
        
            global hAxes;
            global trackingPoint;
            global particles;
            global meanColor;
            
            [p, particles] = particleFilterTracking(M, double(meanColor), particles);
            trackingPoint(1) = p(1, 2);
            trackingPoint(2) = p(2, 2);
            
            h = findobj(hAxes, 'Type', 'line');
            delete(h);
            hold(hAxes, 'on');
            plot(hAxes, trackingPoint(1), trackingPoint(2), 'y+', 'MarkerSize', 15, 'LineWidth', 5);
            plot(hAxes, trackingPoint(1), trackingPoint(2), 'b+', 'MarkerSize', 10, 'LineWidth', 3);
            
            plot(hAxes, particles.X(1, :), particles.X(2, :), 'xw', 'MarkerSize', 6, 'LineWidth', 2);
            
            hold(hAxes, 'off');
        end
        
        function mouseClickedImg(app, hObject, ~)
            % hObject    handle to axes1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global trackingPoint;
            global meanColor;
            
            handles = guidata(hObject);
            pos = get(handles.axes1, 'CurrentPoint');
            trackingPoint = round([pos(1, 1), pos(1, 2)]);
            I = getimage(handles.hImageInput);
            meanColor = I(trackingPoint(2)+(-5:5), trackingPoint(1)+(-5:5), :);
            meanColor = median(reshape(meanColor, [], 3), 1);
            
            hold on;
            h = findobj(gca, 'Type', 'line');
            delete(h);
            plot(handles.axes1, trackingPoint(1), trackingPoint(2), 'y+', 'MarkerSize', 15, 'LineWidth', 5);
            plot(handles.axes1, trackingPoint(1), trackingPoint(2), 'b+', 'MarkerSize', 10, 'LineWidth', 3);
            hold off;
            guidata(hObject, handles);
        end
        
        function updatepreview(app, hObject, event, himage)
        
            img = fliplr(event.Data);
            
            global oldImage;
            global trackingFunction;
            
            if ~isempty(oldImage)
                trackingFunction(cat(4, oldImage, img));
            end
            
            oldImage = img;
            
            % Display image data.
            set(himage, 'CData', img, 'ButtonDownFcn', @app.mouseClickedImg);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trackingWebcam_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to trackingWebcam (see VARARGIN)

            %% Recreate axes manually as axes as uiaxes may create lag (https://de.mathworks.com/matlabcentral/answers/582209-video-preview-has-growing-delay-when-used-in-uifigure-but-not-in-normal-figure#answer_484589)
            ax1 = axes(app.figure1, 'Visible', 'off', 'Units', 'pixels', 'Position', app.axes1.Position);
            delete(handles.axes1)
            handles.axes1 = ax1; % matlab.graphics.axis.Axes instead of matlab.ui.control.UIAxes

            %% setup video input: try to find correct adapter and suitable resolutions & color format
            imaqreset;
            if isunix
                driver = 'linuxvideo';
            elseif ismac
                driver = 'macvideo';
            elseif ispc % windows
                driver = 'winvideo';
            else
                error('Unkown OS')
            end
            vid = videoinput(driver, 1);
            format = vid.VideoFormat;
            formats = imaqhwinfo(driver).DeviceInfo.SupportedFormats';
            if any(contains(formats, regexprep(format, '[0-9]+x[0-9]+', '320x240')))
                format = regexprep(format, '[0-9]+x[0-9]+', '320x240');
            elseif any(contains(formats, regexprep(format, '[0-9]+x[0-9]+', '640x480')))
                format = regexprep(format, '[0-9]+x[0-9]+', '640x480');
            elseif any(contains(formats, regexprep(format, '[0-9]+x[0-9]+', '800x600')))
                format = regexprep(format, '[0-9]+x[0-9]+', '800x600');
            else
                error('Need to select a suitable resolution manually')
            end
%             if any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'RGB24_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'RGB24_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'BGR24_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'BGR24_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'I420_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'I420_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'YUY2_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'YUY2_');
%             elseif any(contains(formats, regexprep(format, '[A-Z0-9]+_', 'YV12_')))
%                 format = regexprep(format, '[A-Z0-9]+_', 'YV12_');
%             else
%                 error('Unkown color format')
%             end
            handles.vidInput = videoinput(driver, 1, format);
            handles.vidInput.ReturnedColorSpace = 'rgb'; % fixes ycbcr2rgb being required manually
            handles.pbuttonToggleCam.Enable = 'on';
            guidata(hObject, handles);

            %% create image buffer for visualization of filters & register custom preview callback
            vidRes = get(handles.vidInput, 'VideoResolution');
            nBands = get(handles.vidInput, 'NumberOfBands');
            img_empty = zeros(vidRes(2), vidRes(1), nBands);
            handles.hImageInput = imshow(img_empty, 'parent', handles.axes1);
            setappdata(handles.hImageInput, 'UpdatePreviewWindowFcn', @app.updatepreview);
            
            % Better use UpdatePreviewWindowFcn as FramesAcquiredFcn events are guaranteed to execute and can be queued, which may cause delay
            % > https://de.mathworks.com/matlabcentral/answers/95642-how-are-events-queued-when-using-the-image-acquisition-toolbox-callback-functions
            % From documentation of 'preview':
            %    The 'UpdatePreviewWindowFcn' will not necessarily be called for every frame that is acquired.
            %    If a new frame is acquired and the 'UpdatePreviewWindowFcn' for the previous frame has not yet finished
            %    executing, no update will be generated for the new frame.  If you need to execute a function for every acquired frame,
            %    use the FramesAcquiredFcn instead.
            %set(handles.vidInput, 'FramesPerTrigger', Inf);
            %set(handles.vidInput, 'FramesAcquiredFcnCount', 1);
            %set(handles.vidInput, 'FramesAcquiredFcn', @app.updatefilter);
            
            %% set global defaults
            global hAxes;
            hAxes = handles.axes1;
            
            global trackingFunction;
            trackingFunction = @app.blockMatcher;
            
            global trackingPoint;
            trackingPoint = [];
            
            global oldImage;
            oldImage = [];
            
            global nParticles;
            nParticles = 50;
            
            global particles;
            particles.X = [randi(vidRes(2), 1, nParticles); randi(vidRes(1), 1, nParticles)];
            particles.V = zeros(2, nParticles);
            
            global meanColor;
            meanColor = [255, 0, 0];            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: CloseMenuItem
        function CloseMenuItem_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CloseMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Close ' get(handles.figure1, 'Name') '?'], ...
                                 ['Close ' get(handles.figure1, 'Name') '...'], ...
                                 'Yes', 'No', 'Yes');
            if strcmp(selection, 'No')
                return;
            end
            
            delete(handles.figure1)
        end

        % Menu selected function: OpenMenuItem
        function OpenMenuItem_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OpenMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            file = uigetfile('*.fig');
            if ~isequal(file, 0)
                open(file);
            end
        end

        % Menu selected function: ExportMenuItem
        function ExportMenuItem_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PrintMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %printdlg(handles.figure1)
            exportapp(handles.figure1, 'trackingWebcam.pdf')
        end

        % Button pushed function: pbuttonToggleCam
        function pbuttonToggleCam_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbuttonToggleCam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (strcmp(get(handles.pbuttonToggleCam, 'String'), 'Start Live View'))
                preview(handles.vidInput, handles.hImageInput);
                set(handles.pbuttonToggleCam, 'String', 'Stop Live View');
            else
                stoppreview(handles.vidInput);
                set(handles.pbuttonToggleCam, 'String', 'Start Live View');
            end
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            %%global filterSelection;
            %%filterSelection = get(handles.popupmenu1, 'Value');
            global trackingFunction;
            global particles;
            global nParticles;
            
            handles = guidata(hObject);
            
            popidx = get(handles.popupmenu1, 'Value');
            switch popidx
                case 1
                    trackingFunction = @app.blockMatcher;
                case 2
                    trackingFunction = @app.particleFilter;
            
                    I = getimage(handles.hImageInput);
                    particles.X = [randi(size(I, 2), 1, nParticles); randi(size(I, 1), 1, nParticles)];
                    particles.V = zeros(2, nParticles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'trackingWebcam';
            app.figure1.Tag = 'figure1';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.OpenMenuItem = uimenu(app.FileMenu);
            app.OpenMenuItem.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuItem_Callback, true);
            app.OpenMenuItem.Text = 'Open ...';
            app.OpenMenuItem.Tag = 'OpenMenuItem';
            app.ExportMenuItem = uimenu(app.FileMenu);
            app.ExportMenuItem.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuItem_Callback, true);
            app.ExportMenuItem.Text = 'Export View ...';
            app.ExportMenuItem.Tag = 'PrintMenuItem';
            app.CloseMenuItem = uimenu(app.FileMenu);
            app.CloseMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuItem_Callback, true);
            app.CloseMenuItem.Separator = 'on';
            app.CloseMenuItem.Text = 'Close';
            app.CloseMenuItem.Tag = 'CloseMenuItem';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Visible = 'off';
            app.axes1.Tag = 'axes1';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Block Matcher', 'Partikelfilter'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Block Matcher';
            app.pbuttonToggleCam = uibutton(app.figure1, 'push');
            app.pbuttonToggleCam.ButtonPushedFcn = createCallbackFcn(app, @pbuttonToggleCam_Callback, true);
            app.pbuttonToggleCam.Tag = 'pbuttonToggleCam';
            app.pbuttonToggleCam.Enable = 'off';
            app.pbuttonToggleCam.Text = 'Start Live View';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackingWebcam(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)trackingWebcam_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end